classdef prac8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        PuntosagenerarporclaseEditField  matlab.ui.control.NumericEditField
        PuntosagenerarporclaseLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        UmbralEditField              matlab.ui.control.NumericEditField
        PuntosacolocarLabel          matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        BanderaDropDown              matlab.ui.control.DropDown
        BanderaDropDownLabel         matlab.ui.control.Label
    end

    
    methods (Access = public)
        %Función que sirve para obtener la ruta relativa del archivo
        function ruta = ObtenerRuta(~,value)
                if(value == "Alemania")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Alemania.jpg";
                elseif(value == "Belgica")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/belgica.jpeg";
                elseif(value == "Colombia")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/colombia.png";
                elseif(value == "Holanda")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Holanda.jpeg";
                elseif(value == "Italia")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Italia.jpeg";
                elseif(value == "Irlanda")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Irlanda.jpeg";
                elseif(value == "Luxemburgo")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/luxemburgo.jpeg";
                elseif(value == "Qatar")
                    ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/qatar.png";
                end            
                return
        end

        %hacer en codigo duro para las dimensiones de las banderas y los
        %colores con impixel y randi([x,x1],1,100)
        %colores con impixel y randi([y,y1],1,100)
        %las banderas son en su mayoria simetricas, por lo que dividir
        %entre n ya sea en x o en y
        %guardar las clases de puntos de las clases
        %Que el usuario indique la cantidad de puntos distribuodos equitativemente y determinar para que valor de T es posible (algoritmo de la teoria de la cadena)
        %x1={g1}
        %x2={g2}
        %g1 si d(x2 ->g1//representativo del grupo(media)) < umbral
        %g2 si d(x2 ->g1) >= umbral
        %regla de la cadeena
        %clasificar con un clasificador de los que conocemos

        
         function [puntos,D] = Generar_puntos(app,cantidad,value)
            ruta = ObtenerRuta(app,value);
            Imagen = imread(ruta);
            conv_coord_pix = imref2d(size(Imagen));
            
            impixel(Imagen)

            figure(2);
            imshow(Imagen,conv_coord_pix);
            title('Imagen en coordenadas pixelares');
            
            if(value == "Alemania") 
                %negro de x(0 a 4160) y(0 a 830)
                %rojo de x(0 a 4160) y(831 a 1664)
                %amarillo de x(0 a 4160) y(1665 a 2496)

                %negro
                nx=randi([1,4160],1,cantidad);
                ny=randi([1,830],1,cantidad);
                
                %rojo
                rx=randi([1,4160],1,cantidad);
                ry=randi([832,1664],1,cantidad);
                
                %amarillo
                ax=randi([1,4160],1,cantidad);
                ay=randi([1665,2496],1,cantidad);

                d1=impixel(Imagen,nx(1,:),ny(1,:));
                d2=impixel(Imagen,rx(1,:),ry(1,:));
                d3=impixel(Imagen,ax(1,:),ay(1,:));

                grid on
                hold on
                plot(nx(1,:),ny(1,:),'ob','LineWidth',1,'MarkerSize',10);
                plot(rx(1,:),ry(1,:),'ok','LineWidth',1,'MarkerSize',10);
                plot(ax(1,:),ay(1,:),'og','LineWidth',1,'MarkerSize',10);
                %plot(P(1,:),P(2,:),'ok','LineWidth',1,'MarkerSize',10,'MarkerFaceColor','y')
                legend('Negro','Rojo','Amarillo')
                title('GRAFICA DE 3 CLASES DE OBJETOS SOBRE UNA IMAGEN');
                D=[d1,d2,d3];
                puntos = [nx,rx,ax;ny,ry,ay];
                    
            elseif(value == "Belgica")
                %negro de x(0 a 1194) y(0 a 2500)
                %rojo de x(1195 a 2393) y(0 a 2500)
                %amarillo de x(2394 a 3586) y(0 a 2500)
                
                %negro
                nx=randi([1,1194],1,cantidad);
                ny=randi([1,2500],1,cantidad);
                
                %rojo
                rx=randi([1195,2393],1,cantidad);
                ry=randi([1,2500],1,cantidad);
                
                %amarillo
                ax=randi([2394,3586],1,cantidad);
                ay=randi([1,2500],1,cantidad);
                
                d1=impixel(Imagen,nx(1,:),ny(1,:));
                d2=impixel(Imagen,rx(1,:),ry(1,:));
                d3=impixel(Imagen,ax(1,:),ay(1,:));

                grid on
                hold on
                plot(nx(1,:),ny(1,:),'*b','LineWidth',1,'MarkerSize',10);
                plot(rx(1,:),ry(1,:),'*k','LineWidth',1,'MarkerSize',10);
                plot(ax(1,:),ay(1,:),'*g','LineWidth',1,'MarkerSize',10);
                %plot(P(1,:),P(2,:),'ok','LineWidth',1,'MarkerSize',10,'MarkerFaceColor','y')
                legend('Negro','Rojo','Amarillo')
                title('GRAFICA DE 3 CLASES DE OBJETOS SOBRE UNA IMAGEN');
                
                D=[d1,d2,d3];
                puntos = [nx,rx,ax;ny,ry,ay];

            elseif(value == "Colombia")
                %amarillo de x(0 a 766) y(0 a 254)
                %azul de x(0 a 766) y(255 a 383)
                %rojo de x(0 a 766) y(384 a 511)

                %amarillo
                amx=randi([1,766],1,cantidad);
                amy=randi([1,254],1,cantidad);
                
                %azul
                ax=randi([1,766],1,cantidad);
                ay=randi([255,383],1,cantidad);
                
                %rojo
                rx=randi([1,766],1,cantidad);
                ry=randi([384,511],1,cantidad);

                d1=impixel(Imagen,amx(1,:),amy(1,:));
                d2=impixel(Imagen,ax(1,:),ay(1,:));
                d3=impixel(Imagen,rx(1,:),ry(1,:));

                grid on
                hold on
                plot(amx(1,:),amy(1,:),'+b','LineWidth',1,'MarkerSize',10);
                plot(ax(1,:),ay(1,:),'+k','LineWidth',1,'MarkerSize',10);
                plot(rx(1,:),ry(1,:),'+g','LineWidth',1,'MarkerSize',10);
                %plot(P(1,:),P(2,:),'ok','LineWidth',1,'MarkerSize',10,'MarkerFaceColor','y')
                legend('Amarillo','Azul','Rojo');
                title('GRAFICA DE 3 CLASES DE OBJETOS SOBRE UNA IMAGEN');
                
                D=[d1,d2,d3];
                puntos = [amx,ax,rx;amy,ay,ry];

            elseif(value == "Holanda")
                %rojo de x(0 a 472) y(0 a 82)
                %blanco de x(0 a 472) y(83 a 166)
                %azul de x(0 a 472) y(167 a 249)

                %rojo
                rx=randi([1,472],1,cantidad);
                ry=randi([1,82],1,cantidad);
                
                %blanco
                bx=randi([1,472],1,cantidad);
                by=randi([83,166],1,cantidad);
                
                %azul
                ax=randi([1,472],1,cantidad);
                ay=randi([167,249],1,cantidad);
                
                d1=impixel(Imagen,rx(1,:),ry(1,:));
                d2=impixel(Imagen,bx(1,:),by(1,:));
                d3=impixel(Imagen,ax(1,:),ay(1,:));

                grid on
                hold on
                plot(rx(1,:),ry(1,:),'ob','LineWidth',1,'MarkerSize',10);
                plot(bx(1,:),by(1,:),'ok','LineWidth',1,'MarkerSize',10);
                plot(ax(1,:),ay(1,:),'og','LineWidth',1,'MarkerSize',10);
                %plot(P(1,:),P(2,:),'ok','LineWidth',1,'MarkerSize',10,'MarkerFaceColor','y')
                legend('Rojo','Blanco','Azul');
                title('GRAFICA DE 3 CLASES DE OBJETOS SOBRE UNA IMAGEN');
                
                D=[d1,d2,d3];
                puntos = [rx,bx,ax;ry,by,ay];
                
            elseif(value == "Italia")
                %verde de x(0 a 1564) y(0 a 3320)
                %blanco de x(1565 a 3336) y(0 a 3320)
                %rojo de x(3337 a 4992) y(0 a 3320)

                %verde
                vx=randi([1,1564],1,cantidad);
                vy=randi([1,3320],1,cantidad);
                
                %blanco
                bx=randi([1565,3336],1,cantidad);
                by=randi([1,3320],1,cantidad);
                
                %rojo
                rx=randi([3337,4992],1,cantidad);
                ry=randi([1,3320],1,cantidad);

                d1=impixel(Imagen,vx(1,:),vy(1,:));
                d2=impixel(Imagen,bx(1,:),by(1,:));
                d3=impixel(Imagen,rx(1,:),ry(1,:));

                grid on
                hold on
                plot(vx(1,:),vy(1,:),'*b','LineWidth',1,'MarkerSize',10);
                plot(bx(1,:),by(1,:),'*k','LineWidth',1,'MarkerSize',10);
                plot(rx(1,:),ry(1,:),'*g','LineWidth',1,'MarkerSize',10);
                %plot(P(1,:),P(2,:),'ok','LineWidth',1,'MarkerSize',10,'MarkerFaceColor','y')
                legend('Verde','Blanco','Rojo');
                title('GRAFICA DE 3 CLASES DE OBJETOS SOBRE UNA IMAGEN');
                
                D=[d1,d2,d3];
                puntos = [vx,bx,rx;vy,by,ry];

            elseif(value == "Irlanda")
                %verde de x(0 a 618) y(0 a 1235)
                %blanco de x(619 a 1238) y(0 a 1235)
                %naranja de x(1239 a 1853) y(0 a 1235)

                %verde
                vx=randi([1,618],1,cantidad);
                vy=randi([1,1235],1,cantidad);
                
                %blanco
                bx=randi([619,1238],1,cantidad);
                by=randi([1,1235],1,cantidad);
                
                %naranja
                nx=randi([1239,1853],1,cantidad);
                ny=randi([1,1235],1,cantidad);
                
                d1=impixel(Imagen,vx(1,:),vy(1,:));
                d2=impixel(Imagen,bx(1,:),by(1,:));
                d3=impixel(Imagen,nx(1,:),ny(1,:));

                grid on
                hold on
                plot(vx(1,:),vy(1,:),'+b','LineWidth',1,'MarkerSize',10);
                plot(bx(1,:),by(1,:),'+k','LineWidth',1,'MarkerSize',10);
                plot(nx(1,:),ny(1,:),'+g','LineWidth',1,'MarkerSize',10);
                %plot(P(1,:),P(2,:),'ok','LineWidth',1,'MarkerSize',10,'MarkerFaceColor','y')
                legend('Verde','Blanco','Naranja');
                title('GRAFICA DE 3 CLASES DE OBJETOS SOBRE UNA IMAGEN');

                D=[d1,d2,d3];
                puntos = [vx,bx,nx;vy,by,ny];

            elseif(value == "Luxemburgo")
                %rojo de x(0 a 3836) y(0 a 796)
                %blanco de x(0 a 3836) y(797 a 1596)
                %azul de x(0 a 3836) y(1597 a 2396)

                %rojo
                nx=randi([1,3836],1,cantidad);
                ny=randi([1,796],1,cantidad);
                
                %blanco
                bx=randi([1,3836],1,cantidad);
                by=randi([797,1596],1,cantidad);
                
                %azul
                ax=randi([1,3836],1,cantidad);
                ay=randi([1597,2396],1,cantidad);
                
                d1=impixel(Imagen,nx(1,:),ny(1,:));
                d2=impixel(Imagen,bx(1,:),by(1,:));
                d3=impixel(Imagen,ax(1,:),ay(1,:));

                grid on
                hold on
                plot(nx(1,:),ny(1,:),'ob','LineWidth',1,'MarkerSize',10);
                plot(bx(1,:),by(1,:),'ok','LineWidth',1,'MarkerSize',10);
                plot(ax(1,:),ay(1,:),'og','LineWidth',1,'MarkerSize',10);
                %plot(P(1,:),P(2,:),'ok','LineWidth',1,'MarkerSize',10,'MarkerFaceColor','y')
                legend('Rojo','Blanco','Azul');
                title('GRAFICA DE 3 CLASES DE OBJETOS SOBRE UNA IMAGEN');

                D=[d1,d2,d3];
                puntos = [nx,bx,ax;ny,by,ay];

            elseif(value == "Qatar")
                %blanco de x(0 a 4160) y(0 a 830)
                %guinda de x(0 a 4160) y(831 a 1664)

                
                %blanco
                bx=randi([1,4160],1,cantidad);
                by=randi([797,1596],1,cantidad);
                
                %guinda
                gx=randi([1,4160],1,cantidad);
                gy=randi([1597,2396],1,cantidad);
                
                d1=impixel(Imagen,bx(1,:),by(1,:));
                d2=impixel(Imagen,gx(1,:),gy(1,:));

                grid on
                hold on
                plot(bx(1,:),by(1,:),'+b','LineWidth',1,'MarkerSize',10);
                plot(gx(1,:),gy(1,:),'+k','LineWidth',1,'MarkerSize',10);
                %plot(P(1,:),P(2,:),'ok','LineWidth',1,'MarkerSize',10,'MarkerFaceColor','y')
                legend('Blanco','Guinda');
                title('GRAFICA DE 3 CLASES DE OBJETOS SOBRE UNA IMAGEN');
                
                D=[d1,d2];
                puntos = [bx,gx;by,gy];     


            end            

        end
        
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BanderaDropDown
        function BanderaDropDownValueChanged(app, event)
            value = app.BanderaDropDown.Value;
            ruta = ObtenerRuta(app,value);
            app.Image.ImageSource = ruta;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            value = app.BanderaDropDown.Value;
            cantidad = app.PuntosagenerarporclaseEditField.Value;
            ruta = ObtenerRuta(app,value);
            Imagen = imread(ruta);
            [Dim_x,Dim_y] = size(Imagen);
            imshow(Imagen)
            title('Bandera original');

            [pts,dts] = Generar_puntos(app,cantidad,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BanderaDropDownLabel = uilabel(app.GridLayout3);
            app.BanderaDropDownLabel.Text = 'Bandera';
            app.BanderaDropDown = uidropdown(app.GridLayout3);
            app.BanderaDropDown.Items = {'Alemania', 'Belgica', 'Colombia', 'Holanda', 'Irlanda', 'Italia', 'Luxemburgo', 'Qatar'};
            app.BanderaDropDown.ValueChangedFcn = createCallbackFcn(app, @BanderaDropDownValueChanged, true);
            app.BanderaDropDown.Placeholder = 'seleccionar';
            app.BanderaDropDown.Value = 'Alemania';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.PuntosacolocarLabel = uilabel(app.GridLayout4);
            app.PuntosacolocarLabel.Text = 'Umbral';
            app.UmbralEditField = uieditfield(app.GridLayout4, 'numeric');
            app.UmbralEditField.Limits = [1 100];
            app.UmbralEditField.RoundFractionalValues = 'on';
            app.UmbralEditField.ValueDisplayFormat = '%.0f';
            app.UmbralEditField.Value = 1;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Alemania.jpg');
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PuntosagenerarporclaseLabel = uilabel(app.GridLayout6);
            app.PuntosagenerarporclaseLabel.Text = {'Puntos a '; 'generar por clase'};
            app.PuntosagenerarporclaseEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PuntosagenerarporclaseEditField.Limits = [4 1000];
            app.PuntosagenerarporclaseEditField.ValueDisplayFormat = '%.0f';
            app.PuntosagenerarporclaseEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prac8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end