classdef appProyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ElementosEncontradosButtonGroup  matlab.ui.container.ButtonGroup
        ArmellaEditField          matlab.ui.control.NumericEditField
        ArmellaLabel              matlab.ui.control.Label
        ColaDePatoEditField       matlab.ui.control.NumericEditField
        ColaDePatoLabel_2         matlab.ui.control.Label
        LlaveAllenEditField       matlab.ui.control.NumericEditField
        LlaveAllenEditFieldLabel  matlab.ui.control.Label
        ArandelasEditField        matlab.ui.control.NumericEditField
        ArandelasEditFieldLabel   matlab.ui.control.Label
        TuercasEditField          matlab.ui.control.NumericEditField
        TuercasEditFieldLabel     matlab.ui.control.Label
        Valor0                    matlab.ui.control.RadioButton
        ImagenesDropDownLabel     matlab.ui.control.Label
        AgregarImagenButton       matlab.ui.control.Button
        ImagenesDropDown          matlab.ui.control.DropDown
        ImagenOriginalLabel_2     matlab.ui.control.Label
        ImagenOriginal            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagen
        LlaveAllen
        Arandela
        Tuerca
        Armella
        ColaDePato
    end
    
    methods (Access = private)
        
        function results = cargarApp(app)
            try %rutas johan
                nombre = "C:\Users\Johan\Documents\GitHub\Matlab\Proyecto\Bases Sossa\IMAG";
            catch 
                try %rutas adad
                    nombre = "C:\Users\19286463\Desktop\Escom\semestre actual\pattern\github\Matlab\Proyecto\Bases Sossa\IMAG";
                catch
                    try %rutas chema
                        %nombre = "C:\Users\Johan\Documents\GitHub\Matlab\Proyecto\Bases Sossa\IMAG";
                    catch exception
                        print(exception);
                    end
                end
            end
            extension = ".BMP";
            numero = "";
            for i=1:+1:120
                if ~(i<105 && i>98)
                    if i<10
                    numero = "00";
                    elseif i<100
                        numero = "0";
                    else
                        numero = "";
                    end
                    texto = strcat(nombre,numero,num2str(i),extension);
                    app.ImagenesDropDown.Items = [app.ImagenesDropDown.Items texto];
                end
            end
            actualizarImagen(app,app.ImagenesDropDown.Value)
        end
        
        function results = actualizarImagen(app,nombre)
            app.imagen = imread(nombre);
            imagesc(app.ImagenOriginal,app.imagen);
            app.LlaveAllen = 0;
            app.Arandela = 0;
            app.Tuerca = 0;
            app.Armella = 0;
            app.ColaDePato = 0;
            analizarImagen(app);
        end
        
        function results = analizarImagen(app)
            app.imagen = imbinarize(app.imagen);
            app.imagen = bwareaopen(app.imagen,30);
            app.imagen = imfill(app.imagen,'holes');
            stats = regionprops(app.imagen,"Perimeter","Area","Centroid","BoundingBox","Circularity","ConvexArea");
            for i =1 : length(stats)
                x = stats(i).BoundingBox;
                valor = buscarElemento(app,stats(i).Circularity,stats(i).Area,stats(i).ConvexArea);
                if valor == "LlaveAllen"
                    app.LlaveAllen = app.LlaveAllen +1;
                elseif valor == "Arandela"
                    app.Arandela = app.Arandela +1;
                elseif valor == "Armella"
                    app.Armella = app.Armella + 1;
                elseif valor == "ColaDePato"
                    app.ColaDePato = app.ColaDePato + 1 ;
                elseif valor == "Tuerca"
                    app.Tuerca = app.Tuerca + 1 ;
                end
            end
            asignarNumeros(app)
        end
        
        function resultado = buscarElemento(app,circularidad,area,convexArea)
            resultado = "no";
            if circularidad >.9
                resultado = "Arandela";
            elseif circularidad<.45 && area > 450 && convexArea<1400
                resultado = "Armella";
            elseif area>1000
                resultado = "ColaDePato";
            elseif circularidad<.45 && circularidad>.14 && area < 450
                resultado = "Tuerca";
            elseif circularidad<.2 && area < 500 && convexArea>1310
                resultado = "LlaveAllen";
            end
        end
        
        function results = asignarNumeros(app)
            app.ArmellaEditField.Value = app.Armella;
            app.ColaDePatoEditField.Value = app.ColaDePato;
            app.LlaveAllenEditField.Value = app.LlaveAllen;
            app.ArandelasEditField.Value = app.Arandela;
            app.TuercasEditField.Value = app.Tuerca;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImagenOriginal.Visible = 'off';
            app.ImagenOriginal.Colormap = gray(256);
            
            cargarApp(app)
        end

        % Value changed function: ImagenesDropDown
        function ImagenesDropDownValueChanged(app, event)
          actualizarImagen(app,app.ImagenesDropDown.Value)
        end

        % Button pushed function: AgregarImagenButton
        function AgregarImagenButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               actualizarImagenOriginal(app, fname);
               
               if ~any(ismember(app.ImagenesDropDown.Items, fname))
                    app.ImagenesDropDown.Items = [app.ImagenesDropDown.Items fname];
               end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagenOriginal = uiaxes(app.UIFigure);
            app.ImagenOriginal.XTick = [];
            app.ImagenOriginal.XTickLabel = {'[]'};
            app.ImagenOriginal.YTick = [];
            app.ImagenOriginalLabel_2 = uilabel(app.UIFigure);
            app.ImagenOriginalLabel_2.Text = 'Imagen Original';
            app.ImagenesDropDown = uidropdown(app.UIFigure);
            app.ImagenesDropDown.Items = {};
            app.ImagenesDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagenesDropDownValueChanged, true);
            app.ImagenesDropDown.Value = {};
            app.AgregarImagenButton = uibutton(app.UIFigure, 'push');
            app.AgregarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarImagenButtonPushed, true);
            app.AgregarImagenButton.Text = 'Agregar Imagen';
            app.ImagenesDropDownLabel = uilabel(app.UIFigure);
            app.ImagenesDropDownLabel.Text = 'Imagenes';
            app.ElementosEncontradosButtonGroup = uibuttongroup(app.UIFigure);
            app.ElementosEncontradosButtonGroup.Title = 'Elementos Encontrados';
            app.Valor0 = uiradiobutton(app.ElementosEncontradosButtonGroup);
            app.Valor0.Visible = 'off';
            app.Valor0.Text = '';
            app.Valor0.Value = true;
            app.TuercasEditFieldLabel = uilabel(app.ElementosEncontradosButtonGroup);
            app.TuercasEditFieldLabel.Text = 'Tuercas';
            app.TuercasEditField = uieditfield(app.ElementosEncontradosButtonGroup, 'numeric');
            app.ArandelasEditFieldLabel = uilabel(app.ElementosEncontradosButtonGroup);
            app.ArandelasEditFieldLabel.Text = 'Arandelas';
            app.ArandelasEditField = uieditfield(app.ElementosEncontradosButtonGroup, 'numeric');
            app.LlaveAllenEditFieldLabel = uilabel(app.ElementosEncontradosButtonGroup);
            app.LlaveAllenEditFieldLabel.Text = 'Llave Allen';
            app.LlaveAllenEditField = uieditfield(app.ElementosEncontradosButtonGroup, 'numeric');
            app.ColaDePatoLabel_2 = uilabel(app.ElementosEncontradosButtonGroup);
            app.ColaDePatoLabel_2.Text = 'Cola De Pato';
            app.ColaDePatoEditField = uieditfield(app.ElementosEncontradosButtonGroup, 'numeric');
            app.ArmellaLabel = uilabel(app.ElementosEncontradosButtonGroup);
            app.ArmellaLabel.Text = 'Armella';
            app.ArmellaEditField = uieditfield(app.ElementosEncontradosButtonGroup, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appProyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end