classdef prac8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        PuntosagenerarporclaseEditField  matlab.ui.control.NumericEditField
        PuntosagenerarporclaseLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        PuntosacolocarEditField      matlab.ui.control.NumericEditField
        PuntosacolocarLabel          matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        BanderaDropDown              matlab.ui.control.DropDown
        BanderaDropDownLabel         matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BanderaDropDown
        function BanderaDropDownValueChanged(app, event)
            value = app.BanderaDropDown.Value;
            if(value == "Alemania")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Alemania.jpg";
                app.Image.ImageSource = ruta;
            elseif(value == "Belgica")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/belgica.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Colombia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/colombia.png";
                app.Image.ImageSource = ruta;
            elseif(value == "Holanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Holanda.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Italia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Italia.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Japon")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/japon.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Irlanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Irlanda.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Luxemburgo")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/luxemburgo.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Qatar")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/qatar.png";
                app.Image.ImageSource = ruta;
            elseif(value == "Blanco")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/blano.jpg";
                app.Image.ImageSource = ruta;
            end            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
                        
            value = app.BanderaDropDown.Value;
            if(value == "Alemania")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Alemania.jpg";                
            elseif(value == "Belgica")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/belgica.jpeg";              
            elseif(value == "Colombia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/colombia.png";                
            elseif(value == "Holanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Holanda.jpeg";                
            elseif(value == "Italia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Italia.jpeg";                
            elseif(value == "Japon")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/japon.jpeg";                
            elseif(value == "Irlanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Irlanda.jpeg";          
            elseif(value == "Luxemburgo")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/luxemburgo.jpeg";
            elseif(value == "Qatar")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/qatar.png";
            elseif(value == "Blanco")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/blano.jpg";
            end

            %ploteo de la imagen
            imagen=imread(ruta);
            image(imagen);
            hold on
            try
                %obtención de los puntos
                num_val=app.PuntosacolocarEditField.Value;

                %almacenamiento de coordenadas ginput
                [x_coordinates,y_coordinates]=ginput(num_val);
                %ploteo de ginput
                scatter(x_coordinates,y_coordinates,'filled','^','c');

                %guardado de los colores de los puntos
                colores=cell(num_val,3);
            
                ind =1;
                while ind ~= num_val+1
                    x_conv=cast(x_coordinates(ind),"int32");
                    y_conv=cast(y_coordinates(ind),"int32");
                    %r
                    colores{ind,1} = imagen(y_conv,x_conv,1);
                    %g
                    colores{ind,2} = imagen(y_conv,x_conv,2);
                    %b
                    colores{ind,3} = imagen(y_conv,x_conv,3);
                    ind = ind+1;
                end

                %Conteo de las clases existentes
                no_clases=0;
                R = colores{1,1};
                G = colores{1,2};
                B = colores{1,3};
                for ind = 2:1:num_val
                    if ((R ~= colores{ind,1}) && (G ~= colores{ind,2}) && (B ~= colores{ind,3}))
                        no_clases = no_clases+1;
                    end
                end
                indices=cell(no_clases,1);
                indices{1,1} = 1;
                for ind = 2:1:num_val
                    if ((R ~= colores{ind,1}) && (G ~= colores{ind,2}) && (B ~= colores{ind,3}))
                        indices{ind,1} = ind;
                    end
                end

                colores_mat=cell2mat(colores);
                %creación de las clases
                clases=cell(no_clases,3);
                for j =1:1:no_clases
                    clases{indices{j,1},1} = colores_mat(indices{j,1},1);
                    clases{indices{j,1},2} = colores_mat(indices{j,1},2);
                    clases{indices{j,1},3} = colores_mat(indices{j,1},3);
                end

                colores_mat
                no_clases
                indices
                clases

                num_clas = 1;
                p_col=1;
                p_gen_clase = app.PuntosagenerarporclaseEditField.Value;
                coords = cell(no_clases,2);
                coords_clase_x = cell(p_gen_clase,1);
                coords_clase_y = cell(p_gen_clase,1);
                while num_clas <= no_clases
                   while p_col <=  p_gen_clase
                      x = cast(rand(1)*100,"int64");
                      y = cast(rand(1)*100,"int64");
                      if (imagen(y,x,1)==clases{num_clas,1}) &&(imagen(y,x,2)==clases{num_clas,2}) && (imagen(y,x,3)==clases{num_clas,3})
                        coords_clase_x(p_col,1) = x; 
                        coords_clase_y(p_col,1) = y; 
                        p_col = p_col+1;
                      elseif p_col == p_gen_clase
                         p_col =1;
                         x_temp = cell2mat(coords_clase_x);
                         y_temp = cell2mat(coords_clase_y);
                         coords{num_clas,1} = x_temp;
                         coords{num_clas,2} = y_temp;
                         num_clas = num_clas+1;
                      elseif (num_clas == no_clases)
                         break
                      end
                   end
                end
                image2(imagen);
                hold on
                %ploteo dinamico como en la 4
                for a  = 1:1:no_clases
                    plot(coords{a,1},coords(a,2))
                end

            catch exception
                    fprintf("Excepcion \n");
                    a = exception
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BanderaDropDownLabel = uilabel(app.GridLayout3);
            app.BanderaDropDownLabel.Text = 'Bandera';
            app.BanderaDropDown = uidropdown(app.GridLayout3);
            app.BanderaDropDown.Items = {'Alemania', 'Belgica', 'Colombia', 'Holanda', 'Irlanda', 'Italia', 'Japon', 'Luxemburgo', 'Qatar', 'Blanco'};
            app.BanderaDropDown.ValueChangedFcn = createCallbackFcn(app, @BanderaDropDownValueChanged, true);
            app.BanderaDropDown.Placeholder = 'seleccionar';
            app.BanderaDropDown.Value = 'Alemania';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.PuntosacolocarLabel = uilabel(app.GridLayout4);
            app.PuntosacolocarLabel.Text = {'Puntos '; 'a'; 'colocar'};
            app.PuntosacolocarEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PuntosacolocarEditField.Limits = [2 20];
            app.PuntosacolocarEditField.RoundFractionalValues = 'on';
            app.PuntosacolocarEditField.ValueDisplayFormat = '%.0f';
            app.PuntosacolocarEditField.Value = 2;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Alemania.jpg');
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.PuntosagenerarporclaseLabel = uilabel(app.GridLayout6);
            app.PuntosagenerarporclaseLabel.Text = {'Puntos a '; 'generar por clase'};
            app.PuntosagenerarporclaseEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PuntosagenerarporclaseEditField.Limits = [4 1000];
            app.PuntosagenerarporclaseEditField.ValueDisplayFormat = '%.0f';
            app.PuntosagenerarporclaseEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prac8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end